classdef odbaseapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        serversMenu                    matlab.ui.container.Menu
        startdataserverMenu            matlab.ui.container.Menu
        startdownloadserverMenu        matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
        solveButton                    matlab.ui.control.Button
        balloontypeListBoxLabel        matlab.ui.control.Label
        balloontypeListBox             matlab.ui.control.ListBox
        stateLabel                     matlab.ui.control.Label
        stateLamp                      matlab.ui.control.Lamp
        burstheightLabel               matlab.ui.control.Label
        undefindedLabel                matlab.ui.control.Label
        launchlatitudeEditFieldLabel   matlab.ui.control.Label
        launchlatitudeEditField        matlab.ui.control.NumericEditField
        launchlongitudeEditFieldLabel  matlab.ui.control.Label
        launchlongitudeEditField       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function dXdt = vdp1(app,t,x)
            global Vburst kt lat0 lon0 h0 terminate  spheroid rogasold  Pold Told Vold Vol0 ...
                hnext vxwold vywold mgas Mtot Mgros marker color
            
            import matlab.net.*
            import matlab.net.http.*
            r = RequestMessage;
            
            if abs(x(3))<=1
                x(3)=-1;
            end
            
            [lat,lon,h]=ned2geodetic(x(1),x(2),x(3),lat0 ,lon0,h0,spheroid);
            
            if abs(h)>hnext
                r = RequestMessage;
                urll=strcat('http://localhost:8080/allvalue/',num2str(lat),'/',num2str(lon),'/',num2str(h),'/0/0');
                uri = URI(urll);
                resp = send(r,uri);
                f=str2num(resp.Body.Data(1));
                pamb=f(4);
                tamb=f(3);
                vxw=f(1);
                vyw=f(2);
                hnext=f(5)
                
            plot(app.UIAxes,t,-x(3),marker,'color',color)
            hold(app.UIAxes,"on");
            else
                pamb=Pold;
                tamb=Told;
                vxw=vxwold;
                vyw=vywold;
                
            end
            
            Ramb=287 ;
            roamb=pamb/(Ramb*tamb);
            % roamb=1.22
            Rhel=2077.1;
            
            
            kt=kt+1;
            vx=x(4);
            vy=x(5);
            vz=x(6);
            
            Vrel=sqrt((vxw-vx)^2+(vyw-vy)^2+(vz)^2);
            vrelz=0-vz;
            vrelx=vxw-vx;
            vrely=vyw-vy;
            ro=roamb;
            rogas=0.164;
            
            rogasvir=pamb*1.15/(Rhel*tamb);
            rogasold=rogasvir;
            mgas=rogas*Vol0;
            
            gama=1.06;
            
            Vol=(Pold/pamb)^(1/gama)*Vold;
            rogasvir=mgas/Vol;
            %rogasold=rogasvir;
            g=9.81;
            B=(ro-rogasvir)*g*Vol;


            L = (Vol*3/4/pi)^(1/3);
            A=pi*L^2;
            visco=1.81*10^-5;
            Re=roamb*Vrel*L/visco;
            
            
            cd= 4.808*(log(Re))^2/100 - 1.406*log(Re) + 10.490;
            % cd =0.75;
            if cd >0.9
                cd =0.85;
            end
            cd=0.05*rand*cd+cd-0.05*rand*cd;
            %
            % figure(34)
            % plot(-x(3),Re,'*')
            % hold on
            % figure(35)
            % plot(-x(3),cd,'*')
            % hold on
            %
            %
            
            
            Drag=0.5*ro*(Vrel)^2*cd*A;


            dXdt(1)=vx;
            dXdt(2)=vy;
            dXdt(3)=vz;
            dXdt(4)=Drag*(vrelx)/Vrel/Mtot;
            dXdt(5)=Drag*vrely/Vrel/Mtot;
            dXdt(6)=(Mgros*9.81-B+Drag*vrelz/Vrel)/Mtot;
            
            %
            % figure(33)
            % plot(-x(3),vz,'v')
            % ylabel('vz')
            
            % hold on
            %
            % figure(323)
            % plot(-x(3),vx,'*')
            % ylabel('vx')
            % hold on
            %
            % figure(313)
            % plot(-x(3),vy,'*')
            % ylabel('vy')
            % hold on
            
            % figure(22)
            % plot(-x(3),dXdt(6),'*')
            % hold on
            %
            % figure(353)
            % plot(-x(3),Vol,'*')
            % ylabel('vol')
            % % hold on
            %
            % figure(2221)
            % plot(-x(3),A,'*')
            % ylabel('A')
            % hold on
            %
            % figure(223)
            % plot(-x(3),B,'*')
            % ylabel('Bync')
            % % hold on
            %
            % figure(2319)
            % plot(t,Mgros*9.81+Drag*vrelz/Vrel,'*')
            % ylabel('Grav + Dragz')
            % hold on
            
            
            % figure(565)
            % plot(-x(3),roamb,'*')
            % ylabel('ro density')
            % hold on
            
            if Vol>=Vburst
                dXdt=dXdt';
                Vol;
                terminate =true;
                return;
            end


            if abs(x(3))>49e3
                dXdt=dXdt';
                terminate =true;
                return;
            end
            if vz>0
                vz
            end
            
            
            Pold=pamb;
            Vold=Vol;
            
            Told=tamb;
            vxwold=vxw;
            vywold=vyw;
            
            dXdt=dXdt';
        end
        
        
        function Y = ode4(app,odefun,tspan,y0,varargin)
            global terminate iend
            
            if ~isnumeric(tspan)
                error('TSPAN should be a vector of integration steps.');
            end
            
            if ~isnumeric(y0)
                error('Y0 should be a vector of initial conditions.');
            end
            
            h = diff(tspan);
            if any(sign(h(1))*h <= 0)
                error('Entries of TSPAN are not in order.')
            end
            
            try
                f0 = feval(odefun,app,tspan(1),y0,varargin{:});
            catch
                msg = ['Unable to evaluate the ODEFUN at t0,y0. ',lasterr];
                error(msg);
            end
            
            y0 = y0(:);   % Make a column vector.
            if ~isequal(size(y0),size(f0))
                error('Inconsistent sizes of Y0 and f(t0,y0).');
            end
            
            neq = length(y0);
            N = length(tspan);
            Y = zeros(neq,N);
            F = zeros(neq,4);
            
            Y(:,1) = y0;
            for i = 2:N
                ti = tspan(i-1);
                hi = h(i-1);
                yi = Y(:,i-1);
                F(:,1) = feval(odefun,app,ti,yi,varargin{:});
                F(:,2) = feval(odefun,app,ti+0.5*hi,yi+0.5*hi*F(:,1),varargin{:});
                F(:,3) = feval(odefun,app,ti+0.5*hi,yi+0.5*hi*F(:,2),varargin{:});
                F(:,4) = feval(odefun,app,tspan(i),yi+hi*F(:,3),varargin{:});
                Y(:,i) = yi + (hi/6)*(F(:,1) + 2*F(:,2) + 2*F(:,3) + F(:,4));
                if terminate
                    Y = Y.';
                    iend=i;
                    return
                end
            end
            
            Y = Y.';
        end
        
        function results = func2(app)
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: solveButton
        function solveButtonPushed(app, event)
            global  kt   spheroid  Pold Told Vold Vol0 hnext terminate iend color marker
            terminate=false;
            Pold =100e3;
            Told =100;
            spheroid = referenceEllipsoid('GRS 80');
            hnext=0;
            Markers = {'.','*','^','>','d','x','d','s','>','<','.','*','^','>','d','x','d','s','>','<','.','*','^','>','d','x','d','s','>','<'};
            color=rand(1,3);
            n=22000;
            iend=n;
            endtime=15000;
            t=linspace(0,endtime,n);
            %% start point in lat0 long0 h0 refrence of NED sys
            global lat0 lon0 h0 mgas Mtot Mgros Vburst
            lat0=33;
            lon0=-55;
            h0=0;
            kt=0;
            x0=0;
            y0=0;
            z0=0;
            
            app.solveButton.Enable = 'off';
            mbs=[200,300,350,450,500,600,700,800,1000,1200,1500,2000,3000,800,1000,1200,2000,3000]/1000;
            mps=[250,250,250,250,250,250,250,250,250,1050,1050,1050,1050,250,250,1050,1050,1050]/1000;
            vol0s=[.83 .97 1.03 1.16 1.22 1.5 1.63 .76 2.01 2.99 3.33 3.89 4.97 1.76 2.01 2.99 3.89 4.97];
            dbs=[300 378 412 472 499 605 653 700 786 863 944 1054 1300 738 828 910 1079 1331]/100;
            
            i = find(contains( app.balloontypeListBox.Items,  app.balloontypeListBox.Value));
            i=i(1);
            marker=Markers{i};
            app.stateLamp.Color = [0 1 0];
            app.stateLabel.Text = 'solving';
            Pold =100e3;
            Told =100;
            spheroid = referenceEllipsoid('GRS 80');
            hnext=0;
            
            mbalon=mbs(i);
            mpay=mps(i);
            Vol0=vol0s(i);
            Vold=Vol0;
            DBurst=dbs(i);
            Vburst=pi*DBurst^3/6;
            rogas=0.164;
            mgas=rogas*Vol0;
            Mtot=mgas+mbalon+mpay;
            Mgros=mbalon+mpay;
            lat0=  app.launchlatitudeEditField.Value;
            lon0=  app.launchlongitudeEditField.Value;
            [y] = ode4(app,@vdp1,t,[x0 y0 z0 0 0 0]);


            app.solveButton.Enable = 'on';
        end

        % Value changed function: balloontypeListBox
        function balloontypeListBoxValueChanged(app, event)
            value = app.balloontypeListBox.Value;
            app.UIFigure.Name =value;


        end

        % Menu selected function: startdataserverMenu
        function startdataserverMenuSelected(app, event)
            app.UIFigure.Name =string(rand);
            command = 'cmd.exe /c start python windapi.py';
            dos(command)
        end

        % Menu selected function: startdownloadserverMenu
        function startdownloadserverMenuSelected(app, event)
            command = 'cmd.exe /c start python G:\apps\balloon\code\py\api\dl.py';
            dos(command)
            
            
        end

        % Value changed function: launchlatitudeEditField
        function launchlatitudeEditFieldValueChanged(app, event)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.serversMenu = uimenu(app.UIFigure);
            app.serversMenu.Text = 'servers';
            app.startdataserverMenu = uimenu(app.serversMenu);
            app.startdataserverMenu.MenuSelectedFcn = createCallbackFcn(app, @startdataserverMenuSelected, true);
            app.startdataserverMenu.Text = 'start data server ';
            app.startdownloadserverMenu = uimenu(app.serversMenu);
            app.startdownloadserverMenu.MenuSelectedFcn = createCallbackFcn(app, @startdownloadserverMenuSelected, true);
            app.startdownloadserverMenu.Text = 'start download server';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'Height(m)')
            app.solveButton = uibutton(app.UIFigure, 'push');
            app.solveButton.ButtonPushedFcn = createCallbackFcn(app, @solveButtonPushed, true);
            app.solveButton.Text = 'solve';
            app.balloontypeListBoxLabel = uilabel(app.UIFigure);
            app.balloontypeListBoxLabel.Text = 'balloon type';
            app.balloontypeListBox = uilistbox(app.UIFigure);
            app.balloontypeListBox.Items = {'TA 200', 'TA 300', 'TA 350', 'TA 450', 'TA 500 ', 'TA 600 ', 'TA 700 ', 'TA 800 ', 'TA 1000 ', 'TA 1200 ', 'TA 1500 ', 'TA 2000 ', 'TA 3000 ', 'TX 800 ', 'TX 1000 ', 'TX 1200 ', 'TX 2000 ', 'TX 3000'};
            app.balloontypeListBox.ValueChangedFcn = createCallbackFcn(app, @balloontypeListBoxValueChanged, true);
            app.balloontypeListBox.Value = 'TX 3000';
            app.stateLabel = uilabel(app.UIFigure);
            app.stateLabel.Text = 'state...';
            app.stateLamp = uilamp(app.UIFigure);
            app.burstheightLabel = uilabel(app.UIFigure);
            app.burstheightLabel.Text = 'burst height:';
            app.undefindedLabel = uilabel(app.UIFigure);
            app.undefindedLabel.Text = 'undefinded';
            app.launchlatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.launchlatitudeEditFieldLabel.Text = 'launch latitude ';
            app.launchlatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.launchlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @launchlatitudeEditFieldValueChanged, true);
            app.launchlongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.launchlongitudeEditFieldLabel.Text = 'launch longitude';
            app.launchlongitudeEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = odbaseapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end